classdef EMG_Comparison_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StatusLabel     matlab.ui.control.Label
        AnalyzeButton   matlab.ui.control.Button
        LoadFileButton  matlab.ui.control.Button
        UIAxesCorr      matlab.ui.control.UIAxes
        UIAxesNorm      matlab.ui.control.UIAxes
        UIAxesOriginal  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select an EMG file.');
            if isequal(file, 0)
                app.StatusLabel.Text = 'No file was selected.';
                return;
            end

            app.FilePath = fullfile(path, file);
            app.EMGData = load(app.FilePath);
            app.StatusLabel.Text = 'File loaded correctly.';
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if isempty(app.FilePath)
                app.StatusLabel.Text = 'Please, load a file first.';
                return;
            end

            num_points = 1000;
            data = app.EMGData;

            if ~isfield(data, 'emg_data_struct') || ~isfield(data.emg_data_struct, 'cut_data')
                app.StatusLabel.Text = 'File invalid structure.';
                return;
            end

            reps = fieldnames(data.emg_data_struct.cut_data);
            muscles = fieldnames(data.emg_data_struct.cut_data.(reps{1}));
            normalized_data = struct();

            for r = 1:length(reps)
                rep_name = reps{r};
                for m = 1:length(muscles)
                    muscle_name = muscles{m};
                    envelope_data = data.emg_data_struct.cut_data.(rep_name).(muscle_name).envelope;

                    if isempty(envelope_data)
                        continue;
                    end

                    envelope_data = fillmissing(envelope_data, 'linear');
                    original_time = linspace(0, 100, length(envelope_data));
                    normalized_time = linspace(0, 100, num_points);
                    interpolated_envelope = interp1(original_time, envelope_data, normalized_time, 'pchip');

                    min_val = min(interpolated_envelope);
                    max_val = max(interpolated_envelope);
                    if max_val > min_val
                        normalized_envelope = (interpolated_envelope - min_val) / (max_val - min_val);
                    else
                        normalized_envelope = interpolated_envelope;
                    end

                    normalized_data.(muscle_name) = normalized_envelope;
                end
            end

            % Cargar datos de referencia
            reference_file = 'representative_emg_curve.mat';
            reference_data = load(reference_file);
            if ~isfield(reference_data, 'emg_results')
                app.StatusLabel.Text = 'Invalid reference file.';
                return;
            end

            % Calcular correlación cruzada
            max_lag = 50;
            correlation_results = struct();
            for m = 1:length(muscles)
                muscle_name = muscles{m};
                user_signal = normalized_data.(muscle_name);
                ref_signal = reference_data.emg_results.(muscle_name).mean;

                [xcorr_values, lags] = xcorr(user_signal, ref_signal, max_lag, 'coeff');
                correlation_results.(muscle_name) = max(xcorr_values);
            end

            % Graficar la señal original y normalizada
            muscle_to_plot = muscles{1};
            plot(app.UIAxesOriginal, data.emg_data_struct.cut_data.(reps{1}).(muscle_to_plot).envelope);
            title(app.UIAxesOriginal, 'Original File');
            xlabel(app.UIAxesOriginal, 'Data');
            ylabel(app.UIAxesOriginal, 'Amplitude');

            plot(app.UIAxesNorm, normalized_data.(muscle_to_plot));
            title(app.UIAxesNorm, 'Normalized signal');
            xlabel(app.UIAxesNorm, 'Normalized time (%)');
            ylabel(app.UIAxesNorm, 'Normalized amplitude');

            % Graficar la correlación cruzada
            bar(app.UIAxesCorr, cell2mat(struct2cell(correlation_results)));
            title(app.UIAxesCorr, 'Cross Correlation with reference data');
            xticks(1:length(muscles));
            xticklabels(muscles);
            ylabel(app.UIAxesCorr, 'Coef. Correlation');

            app.StatusLabel.Text = 'Analysis complete.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'Title')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesNorm = uiaxes(app.UIFigure);
            title(app.UIAxesNorm, 'Title')
            xlabel(app.UIAxesNorm, 'X')
            ylabel(app.UIAxesNorm, 'Y')
            zlabel(app.UIAxesNorm, 'Z')
            app.UIAxesCorr = uiaxes(app.UIFigure);
            title(app.UIAxesCorr, 'Title')
            xlabel(app.UIAxesCorr, 'X')
            ylabel(app.UIAxesCorr, 'Y')
            zlabel(app.UIAxesCorr, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Select a file to start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_Comparison_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end